classdef SimpleTemplateLoadExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure                   matlab.ui.Figure
        MainLayout                   matlab.ui.container.GridLayout
        ImportExportPanel            matlab.ui.container.Panel
        LayoutImportExport           matlab.ui.container.GridLayout
        ExportDatatoExcelFileButton  matlab.ui.control.Button
        DataUITable                  matlab.ui.control.Table
        DataAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % RawTable: Reference to raw imported data table.
        RawTable table

        % DataFilePath: Path to imported and selected file.
        DataFilePath (1,1) string

        % xIndex: Index of plot x data.
        xIndex (1,1) double = 3;

        % yIndex: Index of plot y data.
        yIndex (1,1) double = 4;

        % IsFileLocal: Flag for whether we are processing a local or
        % network file.
        IsFileLocal (1,1) logical = true;

    end %properties (public)
    
    properties (Constant)
        % NetworkPath: Path to network file location.
        NetworkPath (1,1) string = ...
            "\\mathworks\inside\labs\matlab\mwa\DeploymentWorkflowsDemo\";

        % TemplateFileName: Name of template file (located within NetWork
        % path)
        TemplateFileName (1,1) string = ...
            "SampleTemplateFile.xlsx";

    end %properties (constant)

    properties (Dependent)
        % FullTemplateFilePath: Full path to template file on network
        % drive.
        FullTemplateFilePath (1,1) string
    end %properties    

    methods

        function value = get.FullTemplateFilePath(app)
            % Getter method for FullTempalteFilePath.
            %   Generate full pathname for template file.
            value = fullfile(app.NetworkPath,app.TemplateFileName);            
        end %function 

    end %methods (getters)
    
    methods (Access = private)
        
        function generateSampleData(app)
            % generateSampleData: Method to create an example table of data
            % that can be modified and written to the template excel file.
            %  This table is loosely based on the NaV Table shown by
            %  client.

            % Segment Data
            segmentCol = [...
                "CE766AJUST",...
                "CE766HYGE",...
                "CE766HYPAT",...
                "CE766HYPRIVE",...
                "CE766HYPUBLIC",...
                "CE766GCLO",...
                "CE766GFP",...
                "CE766GHYBRIDE",...
                "CE766GLIQUDE",...
                "CE766GPRIVE",...
                "CE766GPUBLIC",...
                "CE766GLEVIER",...
                "CG765MD",...
                "CG765QC",...
                "CG765MD"];

            % Type Data
            typeCol = repmat("PTF_PARENT",15,1);

            % Depart Data
            navDepartCol = randi([0,1000],15,1);
            % Sort this array
            navDepartCol = sort(navDepartCol);

            % Cible Data
            navCibleCol = randi([0,1000],15,1);

            % Reference Data
            referenceCol = [...
                "CORP36_COAO64",...
                "I HQAO",...
                "I HQAO",...
                "I HQAO",...
                "I HQAO",...
                "CORP36_COAO64",...
                "CORP36_COAO64",...
                "CORP36_COAO64",...
                "CORP36_COAO64",...
                "CORP36_COAO64",...
                "CORP36_COAO64",...
                "CORP36_COAO64",...
                "I Q",...
                "I Q",...
                "I Q"];
            % Convert Reference into Categorical
            referenceCol = categorical(referenceCol);

            % -- Combine Into Table
            % Place all columns into a table for raw data evaluation.
            app.RawTable = table(...
                segmentCol(:),typeCol(:),navDepartCol(:),...
                navCibleCol(:),referenceCol(:),...
                'VariableNames',...
                ["Segment","Type","NAV Depart","NAV Cible","Reference"]);            
            
        end %function

        
        function initializeAppControls(app)
            % initializeAppControls: Method to set up any controls that
            % need corresponding app properties to be fully enabled.

            % -- Render Data to UITable
            % Assign RawTable property to Data property of UITable.
            app.DataUITable.Data = app.RawTable;
            app.DataUITable.ColumnName = ...
                app.RawTable.Properties.VariableNames;

            % Set Table Editability and Sortability
            app.DataUITable.ColumnEditable = ...
                [false,false,true,true,false];
            app.DataUITable.ColumnSortable = true;


            % -- Update Plot with Table Data
            % Render numeric columns into plot axis.
            app.updateModifiedPlot();
            
        end %function
        
        function updateModifiedPlot(app)
            % updateModifiedPlot: Method to update plot with table data.
            %   Pulls display data out of table to use for plot. This plot
            %   shows changes user has applied to original data.
            % Get DataUITable Display Data
            t = app.DataUITable.DisplayData;

            % Extract plot X and Y
            x = t{:,app.xIndex};
            y = t{:,app.yIndex};

            % Update X Label
            app.DataAxes.XLabel.String = ...
                app.DataUITable.DisplayData.Properties.VariableNames{...
                app.xIndex};

            % Update Y Label
            app.DataAxes.YLabel.String = ...
                app.DataUITable.DisplayData.Properties.VariableNames{...
                app.yIndex};

            % Plot Data in Axes
            plot(app.DataAxes,x,y,...
                "LineStyle","-",...
                "Marker","diamond");
            %scatter(app.DataAxes,x,y,60,"d","filled");
            
        end %function

        
        function exportData(app)
            % exportData: Method to export data from the UITable to a
            % template excel file hosted on the network drive.
            %   This method should be wrapped in its own class so it can
            %   handle the expected relationship of the export data to the
            %   template file.

            % -- Freeze Current Display Data
            % Gather output table data
            outputData = app.DataUITable.DisplayData;

            % -- Ask User for Filename
            % Get user to specify a directory to download the modified
            % data file for.
            [file,location] = uiputfile("*.xlsx",...
                "Download Modified Data");

            % Ensure selected path is valid
            if isequal(file,0) || isequal(location,0)
                % Bad path chosen
                uialert(app.MainFigure,"Invalid path chosen, abandoning file download.",...
                    "File Download Cancelled",...
                    "Icon","error");
                % Exit method
                return;
            end %endif

            % Concatenate full download path.
            userDownloadFilePath = fullfile(location,file);

            % -- Copy Template File
            % Make a copy of the template file to overwrite sheet data with
            % exported data.
            % Build copy filename
            copyFileName = fullfile(app.NetworkPath,file);

            % Open ProgDlg
            prg = uiprogressdlg(app.MainFigure,...
                "Message","Copying template file for download.",...
                "Title","Exporting Data to File...",...
                "Indeterminate","on");

            % Copy file to same directory on network path.
            [copyStatus, msg] = copyfile(app.FullTemplateFilePath,...
                copyFileName);

            % Close Prog
            close(prg);

            % Ensure Copy was Successful
            if ~copyStatus
                % Error in copying
                uialert(app.MainFigure,msg,...
                    "Data Download Failure: Template Copy Failed",...
                    "Icon","error");
                % Exit method
                return;
            end %endif


            % -- Write Data to Copied Template and Download It
            % For network files, we can append the modified data as a
            % second sheet to the original file.
            % Open PrgDlg
            prg = uiprogressdlg(app.MainFigure,...
                "Message","Writing modified data to new tab in original file...",...
                "Title","Exporting Data to Network File",...
                "Indeterminate","on");

            % Write raw table to new sheet.
            writetable(outputData,copyFileName,...
                "FileType","spreadsheet",...
                "Sheet","Raw Data")

            % Write specific data to Graphics Sheet
            writetable(outputData(:,[3,4]),copyFileName,...
                "FileType","spreadsheet",...
                "Sheet","Graphics");

            % Move the file to the actual location we specified.
            [moveStatus,msg] = ...
                movefile(copyFileName,userDownloadFilePath,'f');

            % Close Prog
            close(prg);
            
            % Ensure Move was Successful
            if ~moveStatus
                % Error in copying
                uialert(app.MainFigure,msg,...
                    "Data Download Failure: File Download Failure",...
                    "Icon","error");
                % Exit method
                return;
            end %endif

            % Alert User to Success
            uialert(app.MainFigure,...
                "Exported modified table to file successful.",...
                "Export Complete",...
                "Icon","success");

        end %function


    end %methods (private)
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Execute application startup methods upon app creation.
            % Create external reference to app object.
            assignin("base","app",app);

            % Generate example static dataset for save/load.
            app.generateSampleData();

            % Initialize application controls.
            app.initializeAppControls();

        end

        % Display data changed function: DataUITable
        function DataUITableDisplayDataChanged(app, event)
            % DataUITableDisplayDataChanged Callback
            %   Method fires whenever the display data changes in the
            %   table. Use this to trigger a repaint of the plot data.
            app.updateModifiedPlot();   
        end

        % Button pushed function: ExportDatatoExcelFileButton
        function ExportDatatoExcelFileButtonPushed(app, event)
            % ExportDataButtonPushed: Method to export the data to the
            % template file.

            % Call export data method.
            app.exportData();

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'MATLAB App';
            app.MainLayout = uigridlayout(app.MainFigure);
            app.DataAxes = uiaxes(app.MainLayout);
            title(app.DataAxes, 'NAV Depart vs Cible')
            xlabel(app.DataAxes, 'X')
            ylabel(app.DataAxes, 'Y')
            zlabel(app.DataAxes, 'Z')
            app.DataUITable = uitable(app.MainLayout);
            app.DataUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.DataUITable.RowName = {};
            app.DataUITable.DisplayDataChangedFcn = createCallbackFcn(app, @DataUITableDisplayDataChanged, true);
            app.ImportExportPanel = uipanel(app.MainLayout);
            app.ImportExportPanel.Title = 'Import/Export Panel';
            app.LayoutImportExport = uigridlayout(app.ImportExportPanel);
            app.ExportDatatoExcelFileButton = uibutton(app.LayoutImportExport, 'push');
            app.ExportDatatoExcelFileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDatatoExcelFileButtonPushed, true);
            app.ExportDatatoExcelFileButton.Text = 'Export Data to Excel File';
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimpleTemplateLoadExample

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MainFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.MainFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end